classdef DIGRAFICAS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        DeclickparareactivarlaLabel    matlab.ui.control.Label
        SihacerradolagraficaaccidentalmenteLabel  matlab.ui.control.Label
        VergraficaButton               matlab.ui.control.Button
        VerMatrizEnPrograma            matlab.ui.control.Button
        NoButton                       matlab.ui.control.Button
        Ya_existe                      matlab.ui.control.Label
        ObtenerLasMatricesLabel        matlab.ui.control.Label
        VerticeLabel_2                 matlab.ui.control.Label
        VerticeLabel_1                 matlab.ui.control.Label
        bucle                          matlab.ui.control.Label
        boton_cerrado_continuar        matlab.ui.control.Button
        advertencia_incidencia         matlab.ui.control.Label
        Num_arista                     matlab.ui.control.Label
        RegresaralmenuprincipalButton  matlab.ui.control.Button
        MatrizadyacenciaButton         matlab.ui.control.Button
        MatrizIncidenciaButton         matlab.ui.control.Button
        IngresarArista                 matlab.ui.control.Button
        AlLabel                        matlab.ui.control.Label
        DELLabel                       matlab.ui.control.Label
        VerticeFinal                   matlab.ui.control.Spinner
        VerticeInicial                 matlab.ui.control.Spinner
        IngreselaaristaLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
       
        G; % grafo
        h;%el plot
        i=1;%contador
        ID_aristas=[];%%este vector almacena el ID de cada arista
        cantidad_vertices;%esta variable almacena la cantidad de aristas de la grafica
        numero_arista=2;%indica que arista se va a ingresar 
        Detector_paralelas=0; % este atributo sera un gatillo que se activa si detecta paralelas
        visualizar_tabla;
    end
    
    methods (Access = private)
        
         function  etiquetas(app)
            app.ID_aristas(app.i)=app.i;
            app.i=app.i+1;
        end
        
        function results = Detectar_paralelas(app,vertice_inicial,vertice_final)
            buscar=app.G.findedge(vertice_inicial,vertice_final);
            if buscar==0
                results=0;
            else
                results=1;
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, vertices)
            app.cantidad_vertices=vertices;%se establece la cantidad de vertices
            limites=[];%este vector almacenara los limites del spinner
            limites(1)=1;%se establece que el spiner empezara en 1
            limites(2)=vertices;%% el spiner acabara en la cantidad de vertices
            app.G=digraph;%% se construye la digrafica
            app.G=app.G.addnode(vertices);%se construye la grafica la cantidad de vertices      % metodo constructr
            app.VerticeInicial.Limits=limites;
            app.VerticeFinal.Limits=limites;
            app.h=plot(app.G);%se plotea la grafica
            app.visualizar_tabla=0;
        end

        % Button pushed function: IngresarArista
        function IngresarAristaButtonPushed(app, event)
            vertice_inicial=app.VerticeInicial.Value;%se recupera la posicion del espinner vertice inicial y se almacena en vertice inicial
            vertice_final=app.VerticeFinal.Value;%se recupera el valor del espiner vertice final y se almacena en vertice final
            paralela=Detectar_paralelas(app,vertice_inicial,vertice_final);
            
            if paralela==1 && app.Detector_paralelas==0
                app.Ya_existe.Visible='on';
                app.Ya_existe.Enable='on';
                app.Ya_existe.Text=char("ya existe por lo menos una linea que va del vertice "+vertice_inicial+" al vertice "+ vertice_final+", deseas ingresarla nuevamente como paralela??");
                app.IngresarArista.Text='Si';
                app.DELLabel.Visible='off';
                
                app.AlLabel.Visible='off';% se desactiva el label que dice al
                app.VerticeInicial.Visible='off';%se desactiva el spinner de vertice inicial
                app.VerticeFinal.Visible='off';%se desactiva el spinner de vertice final
                app.VerticeLabel_2.Text='';%borra el label que dice vertice
                app.VerticeLabel_1.Text='';%borra el label que dice vertice
                
                app.Detector_paralelas=1;%activa el detector de paralelas
                    
                app.NoButton.Enable='on';
                app.NoButton.Visible='on';

                %desactiva el boton terminado ingresar aristas
                app.boton_cerrado_continuar.Enable='off';
                app.boton_cerrado_continuar.Visible='off';

            else


                app.advertencia_incidencia.Text='';
                app.G=app.G.addedge(vertice_inicial,vertice_final);
                etiquetas(app);
                app.h=plot(app.G);
                labeledge(app.h,app.ID_aristas,app.ID_aristas);
                app.Num_arista.Text=char(""+app.numero_arista);
                app.numero_arista=app.numero_arista+1;
                
                
                if app.Detector_paralelas==1
                     app.AlLabel.Visible='on';% se desactiva el label que dice al
                     app.VerticeInicial.Visible='on';%se desactiva el spinner de vertice inicial
                     app.VerticeFinal.Visible='on';%se desactiva el spinner de vertice final
                     app.VerticeLabel_2.Text='vertice';%borra el label que dice vertice
                     app.VerticeLabel_1.Text='vertice';%borra el label que dice vertice
                     app.Ya_existe.Visible='off';
                     app.Ya_existe.Enable='off';
                     app.DELLabel.Visible='on';
                
                     app.IngresarArista.Text='Ingresar Arista';
                     app.Detector_paralelas=0;
                     app.NoButton.Enable='off';
                     app.NoButton.Visible='off';
            
                    app.boton_cerrado_continuar.Enable='on';
                    app.boton_cerrado_continuar.Visible='on';
            
                end
                
            end


        end

        % Button pushed function: MatrizIncidenciaButton
        function MatrizIncidenciaButtonPushed(app, event)
                
                vertices=app.G.numnodes;%se obtienen el numero de vertices
                aristas=app.G.numedges;% se obtiene el numero de aristas
                variable_names={};% en esta se almacenaran los nombres de las columnas
                matriz={};%se almacenara la matria
                inicio=0;
                final=0;
                if aristas==0
                    app.advertencia_incidencia.Text='Ingresa al menos una arista para continuar!!';
                    return;
                end
                system('cerrar_excel.bat');

                for index=1:(aristas+1)%eate for llena la parte superior de la hoja de excel
                    if index==1
                        variable_names(1)={"Aristas/Vertices"};
                    else
                        variable_names(index)={"e"+(index-1)};
                    end    
                end%llave final del for

                for index=1:vertices% este for identidica el vertice
                    matriz(index,1)={index};
                end%final del for

                for columna=1:aristas%for externo
                    [inicio,final]=app.G.findedge(columna);
                    for fila=1:vertices
                        if fila==inicio && fila==final
                            matriz(fila,columna+1)={2};
                        else
                            if fila==inicio 
                                matriz(fila,columna+1)={1};
                            elseif fila==final                    
                                matriz(fila,columna+1)={-1};
                            else
                                 matriz(fila,columna+1)={0};
                            end
                        end
                    end
                end%final for externo
            
            str=string(variable_names);
            T=cell2table(matriz,"VariableNames",str);
                
           if app.visualizar_tabla==0
                 ruta_archivo = 'digrafica_matriz_incidencia.xlsx';
                 comando = ['del ', ruta_archivo];   
                 system(comando);
                 writetable(T,'digrafica_matriz_incidencia.xlsx');
            
                 fid = fopen('abrir_archivo.bat', 'w');
                 fprintf(fid, 'start "" "digrafica_matriz_incidencia.xlsx"\n');
                 fclose(fid);
                 % Ejecutar el archivo .bat
                 system('abrir_archivo.bat');

                 % Eliminar el archivo .bat
                 system('del abrir_archivo.bat');

            else
                tablas(str,T,'Incidencia');
            
            end


        end

        % Button pushed function: MatrizadyacenciaButton
        function MatrizadyacenciaButtonPushed(app, event)
            num_vertices=app.cantidad_vertices;% esta variable almacena la cantidad de vertices de la grafica
            variable_names={};
            matriz={};
            
           
            system('cerrar_excel.bat');
            

            for index=1:(num_vertices+1)%eate for llena la parte superior de la hoja de excel
                    if index==1
                        variable_names(1)={"Vertices"};
                    else
                        variable_names(index)={""+(index-1)};
                    end    
             end%llave final del for
             
            
             for index=1:num_vertices% este for identidica el vertice
                    matriz(index,1)={index};
             end%final del for


             for filas=1:num_vertices
                for columnas=1:num_vertices
                    valor=findedge(app.G,filas,columnas);
                    if valor==0
                        matriz(filas,columnas+1)={0};
                    else
                        matriz(filas,columnas+1)={1};
                    end
                end
             end
            
            
            str=string(variable_names);
            T=cell2table(matriz,"VariableNames",str);


             if app.visualizar_tabla==0
                 ruta_archivo = 'digrafica_matriz_adyacencia.xlsx';
                 comando = ['del ', ruta_archivo];   
                 system(comando);
                 writetable(T,'digrafica_matriz_adyacencia.xlsx');
            
                 fid = fopen('abrir_archivo.bat', 'w');
                 fprintf(fid, 'start "" "digrafica_matriz_adyacencia.xlsx"\n');
                 fclose(fid);
                 % Ejecutar el archivo .bat
                  system('abrir_archivo.bat');
            
                % Eliminar el archivo .bat
                 system('del abrir_archivo.bat');

            else
                tablas(str,T,'Adyacencia');
            
            end


        end

        % Button pushed function: RegresaralmenuprincipalButton
        function RegresaralmenuprincipalButtonPushed(app, event)
            system('cerrar_excel.bat');
            
            ruta_archivo = 'digrafica_matriz_incidencia.xlsx';
            comando = ['del ', ruta_archivo];   
            system(comando);
            
            ruta_archivo = 'digrafica_matriz_adyacencia.xlsx';
            comando = ['del ', ruta_archivo];   
            system(comando);

            app2();
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            
        end

        % Button pushed function: boton_cerrado_continuar
        function boton_cerrado_continuarPushed(app, event)
            app.VerMatrizEnPrograma.Visible='on';
            app.VerMatrizEnPrograma.Enable='on';
            app.ObtenerLasMatricesLabel.Enable='on';%muestra visible el label que dice obtener matrices
            app.ObtenerLasMatricesLabel.Visible='on';%activa el label que dice obtener matrices
            app.IngresarArista.Visible='off';%vuelve invisible el boton ingresar arista
            app.IngresarArista.Enable='off';%deshabilita el boton ingresar arista
            app.IngreselaaristaLabel.Visible='off';%desactiva el mensaje del label Ingresa la arista
            app.Num_arista.Visible='off';%desactiva el label que muestra el numero de aristya
            app.AlLabel.Visible='off';% se desactiva el label que dice al
            app.VerticeInicial.Visible='off';%se desactiva el spinner de vertice inicial
            app.VerticeFinal.Visible='off';%se desactiva el spinner de vertice final
            app.VerticeLabel_2.Text='';%borra el label que dice vertice
            app.VerticeLabel_1.Text='';%borra el label que dice vertice
            app.DELLabel.Visible='off';%este label contiene el mensaje "DEL"
            app.boton_cerrado_continuar.Visible='off';% se desactiva el boton que dice "he terminado ingresar aristas"
            app.advertencia_incidencia.Visible='on';%activa el label que advierte que se requiere al menos una arista
            app.MatrizIncidenciaButton.Visible='on';%hace visible el mensaje boton incidencia
            app.MatrizadyacenciaButton.Visible='on';%hace vicible el boton matriz adyacencia
            app.bucle.Visible='on';% imprime el mensaje que dice "2 implica un bucle"
        end

        % Button pushed function: NoButton
        function NoButtonPushed(app, event)
                app.AlLabel.Visible='on';% se activa el label que dice al
                app.VerticeInicial.Visible='off';%se activa el spinner de vertice inicial
                app.VerticeFinal.Visible='off';%se activa el spinner de vertice final
                app.VerticeLabel_2.Text='vertice';%llena el label que dice vertice
                app.VerticeLabel_1.Text='vertice';%llena el label que dice vertice
                app.IngresarArista.Text='Ingresar Arista';%restaura el texto del boton ingresar arista
                app.Ya_existe.Visible='off';%desactiva el texto ya exista
                app.Ya_existe.Enable='off';%desactiva el tecto ya existe
                app.Detector_paralelas=0;%reinicia el detector de paralelas
                                            
                app.NoButton.Visible='off';
                app.NoButton.Enable='off';

                app.VerticeFinal.Visible='on';
                app.VerticeInicial.Visible='on';
                app.DELLabel.Visible='on';

                app.boton_cerrado_continuar.Enable='on';
                    app.boton_cerrado_continuar.Visible='on';
        end

        % Button pushed function: VerMatrizEnPrograma
        function VerMatrizEnProgramaButtonPushed(app, event)
            app.VerMatrizEnPrograma.Visible='off';
            app.VerMatrizEnPrograma.Enable='off';
            app.visualizar_tabla=1;

        end

        % Button pushed function: VergraficaButton
        function VergraficaButtonPushed(app, event)
            app.h=plot(app.G);
                labeledge(app.h,app.ID_aristas,app.ID_aristas);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.IngreselaaristaLabel = uilabel(app.UIFigure);
            app.IngreselaaristaLabel.Text = 'Ingrese la arista';
            app.VerticeInicial = uispinner(app.UIFigure);
            app.VerticeInicial.AllowEmpty = 'on';
            app.VerticeFinal = uispinner(app.UIFigure);
            app.VerticeFinal.AllowEmpty = 'on';
            app.DELLabel = uilabel(app.UIFigure);
            app.DELLabel.Text = 'DEL';
            app.AlLabel = uilabel(app.UIFigure);
            app.AlLabel.Text = 'Al';
            app.IngresarArista = uibutton(app.UIFigure, 'push');
            app.IngresarArista.ButtonPushedFcn = createCallbackFcn(app, @IngresarAristaButtonPushed, true);
            app.IngresarArista.Text = 'Ingresar la Arista';
            app.MatrizIncidenciaButton = uibutton(app.UIFigure, 'push');
            app.MatrizIncidenciaButton.ButtonPushedFcn = createCallbackFcn(app, @MatrizIncidenciaButtonPushed, true);
            app.MatrizIncidenciaButton.Visible = 'off';
            app.MatrizIncidenciaButton.Text = 'Matriz Incidencia';
            app.MatrizadyacenciaButton = uibutton(app.UIFigure, 'push');
            app.MatrizadyacenciaButton.ButtonPushedFcn = createCallbackFcn(app, @MatrizadyacenciaButtonPushed, true);
            app.MatrizadyacenciaButton.Visible = 'off';
            app.MatrizadyacenciaButton.Text = 'Matriz adyacencia';
            app.RegresaralmenuprincipalButton = uibutton(app.UIFigure, 'push');
            app.RegresaralmenuprincipalButton.ButtonPushedFcn = createCallbackFcn(app, @RegresaralmenuprincipalButtonPushed, true);
            app.RegresaralmenuprincipalButton.Text = 'Regresar al menu principal';
            app.Num_arista = uilabel(app.UIFigure);
            app.Num_arista.Text = '1';
            app.advertencia_incidencia = uilabel(app.UIFigure);
            app.advertencia_incidencia.Visible = 'off';
            app.advertencia_incidencia.Text = 'Debes ingresar al menos una arista para esta opcion';
            app.boton_cerrado_continuar = uibutton(app.UIFigure, 'push');
            app.boton_cerrado_continuar.ButtonPushedFcn = createCallbackFcn(app, @boton_cerrado_continuarPushed, true);
            app.boton_cerrado_continuar.Text = 'He terminado de Ingresar Aristas, dame las matrices';
            app.bucle = uilabel(app.UIFigure);
            app.bucle.Visible = 'off';
            app.bucle.Text = 'Un 2 indica bucles';
            app.VerticeLabel_1 = uilabel(app.UIFigure);
            app.VerticeLabel_1.Text = 'Vertice';
            app.VerticeLabel_2 = uilabel(app.UIFigure);
            app.VerticeLabel_2.Text = 'Vertice';
            app.ObtenerLasMatricesLabel = uilabel(app.UIFigure);
            app.ObtenerLasMatricesLabel.Enable = 'off';
            app.ObtenerLasMatricesLabel.Visible = 'off';
            app.ObtenerLasMatricesLabel.Text = 'Obtener Las Matrices';
            app.Ya_existe = uilabel(app.UIFigure);
            app.Ya_existe.Enable = 'off';
            app.Ya_existe.Visible = 'off';
            app.Ya_existe.Text = 'Ya existe la arista que va del vertice  al vertice 2, deseas ingresarla otra vez como una linea paralela??';
            app.NoButton = uibutton(app.UIFigure, 'push');
            app.NoButton.ButtonPushedFcn = createCallbackFcn(app, @NoButtonPushed, true);
            app.NoButton.Enable = 'off';
            app.NoButton.Visible = 'off';
            app.NoButton.Text = 'No';
            app.VerMatrizEnPrograma = uibutton(app.UIFigure, 'push');
            app.VerMatrizEnPrograma.ButtonPushedFcn = createCallbackFcn(app, @VerMatrizEnProgramaButtonPushed, true);
            app.VerMatrizEnPrograma.Enable = 'off';
            app.VerMatrizEnPrograma.Visible = 'off';
            app.VerMatrizEnPrograma.Text = 'Ver Matrices dentro del programa';
            app.VergraficaButton = uibutton(app.UIFigure, 'push');
            app.VergraficaButton.ButtonPushedFcn = createCallbackFcn(app, @VergraficaButtonPushed, true);
            app.VergraficaButton.Text = 'Ver grafica';
            app.SihacerradolagraficaaccidentalmenteLabel = uilabel(app.UIFigure);
            app.SihacerradolagraficaaccidentalmenteLabel.Text = 'Si ha cerrado la grafica accidentalmente';
            app.DeclickparareactivarlaLabel = uilabel(app.UIFigure);
            app.DeclickparareactivarlaLabel.Text = 'De click para reactivarla';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DIGRAFICAS(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end