classdef GRAFICAS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        VergraficaButton              matlab.ui.control.Button
        DeclickparareactivarlaLabel   matlab.ui.control.Label
        SihacerradolagraficaaccidentalmenteLabel  matlab.ui.control.Label
        VerMatrizEnPrograma           matlab.ui.control.Button
        NoButton                      matlab.ui.control.Button
        ya_existe_la_arista           matlab.ui.control.Label
        verticeLabel_2                matlab.ui.control.Label
        verticeLabel_1                matlab.ui.control.Label
        ObtenerlasMatricesLabel       matlab.ui.control.Label
        acabe_ingresar_aristas        matlab.ui.control.Button
        advertencia_incidencia        matlab.ui.control.Label
        Num_arista                    matlab.ui.control.Label
        Ingrese_arista                matlab.ui.control.Label
        MatrizIncidenciaButton        matlab.ui.control.Button
        MatrizAdyacenciaButton        matlab.ui.control.Button
        RegresaraMenuPrincipalButton  matlab.ui.control.Button
        IngresarAristaButton          matlab.ui.control.Button
        ConLabel                      matlab.ui.control.Label
        Vertice2                      matlab.ui.control.Spinner
        Vertice1                      matlab.ui.control.Spinner
        ContextMenu                   matlab.ui.container.ContextMenu
        Menu                          matlab.ui.container.Menu
        Menu2                         matlab.ui.container.Menu
    end

    
    properties (Access = private)
       
        G; % Description
        h;%se almacena el plot
        i=1;%contador
        %s=[];%vector s
        %t=[];%vector t  
        ID_aristas=[];%%este vector almacena el ID de cada arista
        cantidad_vertices;%esta variable almacena la cantidad de aristas de la grafica
        numero_arista=2;%indica que arista se va a ingresar 
        visualizar_tabla;
        Detector_paralelas=0; % este atributo sera un gatillo que se activa si detecta paralelas
    end
    
    methods (Access = private)
        
        function  etiquetas(app)
            app.ID_aristas(app.i)=app.i;
            app.i=app.i+1;
        end
        
        function results = Detectar_paralelas(app,vertice_inicial,vertice_final)
             buscar=app.G.findedge(vertice_inicial,vertice_final);
             if buscar==0
                results=0;
             else
                results=1;
             end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, vertices)
            app.cantidad_vertices=vertices;
            limites=[];
            limites(1)=1;
            limites(2)=vertices;
            app.G=graph;
            app.G=app.G.addnode(vertices);      % metodo constructr
            app.Vertice1.Limits=limites;
            app.Vertice2.Limits=limites;
            app.h=plot(app.G);
            app.visualizar_tabla=0;
        end

        % Button pushed function: IngresarAristaButton
        function IngresarAristaButtonPushed(app, event)
            
            vertice_inicial=app.Vertice1.Value;%se recupera la posicion del espinner vertice inicial y se almacena en vertice inicial
            vertice_final=app.Vertice2.Value;%se recupera el valor del espiner vertice final y se almacena en vertice final
            paralela=Detectar_paralelas(app,vertice_inicial,vertice_final);
            
            if paralela==1 && app.Detector_paralelas==0
                %activa el label que muestra ek mensaje " ya existe esa arista"
                app.ya_existe_la_arista.Visible='on';
                app.ya_existe_la_arista.Enable='on';
                app.ya_existe_la_arista.Text=char("ya existe por lo menos una arista del vertice "+vertice_inicial+" con el vertice "+vertice_final+", deseas ingresarla nuevamente como paralela ");
                
                %cambia el texto del boton ingresar arista
                app.IngresarAristaButton.Text='si';
                
                %activa el boton no
                app.NoButton.Enable='on';
                app.NoButton.Visible='on';

                %desactiva los spinners
                app.Vertice1.Enable='off';
                app.Vertice2.Enable='off';
                app.Vertice1.Visible='off';
                app.Vertice2.Visible='off';

                %desactiva las etiquetas vertice
                app.verticeLabel_1.Visible='off';
                app.verticeLabel_2.Visible='off';

                %desactiva el label "con"
                app.ConLabel.Visible='off';
                app.ConLabel.Enable='off';
                app.Detector_paralelas=1;%activa el detector de paralelas

                %desactiva el boton acabe ingresar aristas
                app.acabe_ingresar_aristas.Visible='off';
                app.acabe_ingresar_aristas.Enable='off';
            else
            
                app.advertencia_incidencia.Text='';
                app.G=app.G.addedge(app.Vertice1.Value,app.Vertice2.Value);
                etiquetas(app);
                app.h=plot(app.G);
                labeledge(app.h,app.ID_aristas,app.ID_aristas);
                app.Num_arista.Text=char(""+app.numero_arista);
                app.numero_arista=app.numero_arista+1;
            
                if app.Detector_paralelas==1
                    app.Detector_paralelas=0;
                    %desactiva el label que dice " ya existe esa arista"
                    app.ya_existe_la_arista.Visible='off';
                    app.ya_existe_la_arista.Enable='off';
                    
                    %cambia el texto del boton ingresar arista
                    app.IngresarAristaButton.Text='Ingresar Arista';
                        
                    %desactiva el boton no
                    app.NoButton.Enable='off';
                    app.NoButton.Visible='off';
                

                     %ctiva los spinners
                    app.Vertice1.Enable='on';
                    app.Vertice2.Enable='on';
                    app.Vertice1.Visible='on';
                    app.Vertice2.Visible='on';

                    %activa las etiquetas vertice
                      
                    app.verticeLabel_1.Visible='on';
                    app.verticeLabel_2.Visible='on';

                                     %activa el boton acabe ingresar aristas
                    app.acabe_ingresar_aristas.Visible='on';
                    app.acabe_ingresar_aristas.Enable='on';
                
                 end
            
            end    


        end

        % Button pushed function: MatrizAdyacenciaButton
        function MatrizAdyacenciaButtonPushed(app, event)
            num_vertices=app.cantidad_vertices;% esta variable almacena la cantidad de vertices de la grafica
            variable_names={};
            matriz={};
            valor=0;
            
            system('cerrar_excel.bat');
            for filas=0:num_vertices%%este for reccorre las filas
                for columnas=1:(num_vertices+1)% este for recorre las columnas


                    if filas==0%%este if controla el encabezado
                        if columnas == 1
                            variable_names(1)={"Vertice"};
                        else   
                            variable_names(columnas)={""+(columnas-1)};
                        end
                    
                    else%%else condicional
                        
                        
                        if columnas==1
                            matriz(filas,columnas)={filas};
                        else
                            
                            valor=findedge(app.G,filas,columnas-1);%DEVUELVE 0 SI EXISTE LA ARISTA
                            if valor==0
                                matriz(filas,columnas)={0};
                            else
                                matriz(filas,columnas)={1};
                            end

                        end

                    end%%final condicional


                end%%llave final for interno
            end%%llafi final for externo
           
            str=string(variable_names);
            T=cell2table(matriz,"VariableNames",str);


              if app.visualizar_tabla==0
                     ruta_archivo = 'matriz_adyacencia.xlsx';
                     comando = ['del ', ruta_archivo];   
                     system(comando);
                     writetable(T, 'matriz_adyacencia.xlsx');
                        % Crear el archivo .bat
                     fid = fopen('abrir_archivo.bat', 'w');
                     fprintf(fid, 'start "" "matriz_adyacencia.xlsx"\n');
                     fclose(fid);
                       % Ejecutar el archivo .bat
                     system('abrir_archivo.bat');
                    
                    % Eliminar el archivo .bat
                    system('del abrir_archivo.bat');

              else
                tablas(str,T,'Adyacencia');
            
              end


        end

        % Button pushed function: MatrizIncidenciaButton
        function MatrizIncidenciaButtonPushed(app, event)
                vertices=app.G.numnodes;%se obtienen el numero de vertices
                aristas=app.G.numedges;% se obtiene el numero de aristas
                variable_names={};% en esta se almacenaran los nombres de las columnas
                matriz={};%se almacenara la matria
                a=0;
                b=0;
                if aristas==0
                    app.advertencia_incidencia.Text='Ingresa al menos una arista para continuar!!';
                    return;
                end
                system('cerrar_excel.bat');
                for index=1:(aristas+1)%eate for llena la parte superior de la hoja de excel
                    if index==1
                        variable_names(1)={"Aristas/Vertices"};
                    else
                        variable_names(index)={"e"+(index-1)};
                    end    
                end%llave final del for


                for index=1:vertices% este for identidica el vertice
                    matriz(index,1)={index};
                end%final del for
                
                %este for llenara la matriz de adyacencia

                for columna=1:aristas%for externo
                    [a,b]=app.G.findedge(columna);
                    for fila=1:vertices
                        if a==fila || b==fila
                            matriz(fila,columna+1)={1};
                        else
                            matriz(fila,columna+1)={0};
                        end
                    end
                end%final for externo
           
            str=string(variable_names);
            T=cell2table(matriz,"VariableNames",str);


             if app.visualizar_tabla==0
                      ruta_archivo = 'matriz_incidencia.xlsx';
                      comando = ['del ', ruta_archivo];   
                      system(comando);
                      writetable(T,'matriz_incidencia.xlsx');

                        % Crear el archivo .bat
                      fid = fopen('abrir_archivo.bat', 'w');
                      fprintf(fid, 'start "" "matriz_incidencia.xlsx"\n');
                      fclose(fid);
                       % Ejecutar el archivo .bat
                      system('abrir_archivo.bat');
                    
                    % Eliminar el archivo .bat
                      system('del abrir_archivo.bat');

              else
                tablas(str,T,'Incidencia');
            
              end

        end

        % Button pushed function: RegresaraMenuPrincipalButton
        function RegresaraMenuPrincipalButtonPushed(app, event)
            system('cerrar_excel.bat');
            ruta_archivo = 'matriz_adyacencia.xlsx';
            comando = ['del ', ruta_archivo];   
            system(comando);
            ruta_archivo = 'matriz_incidencia.xlsx';
            comando = ['del ', ruta_archivo];   
            system(comando);
            app2();
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            
        end

        % Button pushed function: acabe_ingresar_aristas
        function acabe_ingresar_aristasPushed(app, event)
             app.VerMatrizEnPrograma.Visible='on';
             app.VerMatrizEnPrograma.Enable='on';
             %desactiva el primer label de vertice 
            app.verticeLabel_1.Visible='off';
            app.verticeLabel_1.Enable='off';
            %desactiva el segundo label de vertice
            
            app.verticeLabel_2.Visible='off';
            app.verticeLabel_2.Enable='off';
            
            %desactiva el  label con
            app.ConLabel.Visible='off';
            app.ConLabel.Enable='off';

                %desactiva el spinner vertices 1
            app.Vertice1.Enable='off';
            app.Vertice1.Visible='off';
            
                %desactiva el spinner vertices 2
            app.Vertice2.Enable='off';
            app.Vertice2.Visible='off';

                %desactiva el mensaje "ingresar aristas"
            app.Ingrese_arista.Enable='off';
            app.Ingrese_arista.Visible='off';
            
                %desactiva el numero de arista en la que estamos ""
            app.Num_arista.Enable='off';
            app.Num_arista.Visible='off';

            %desactiva el boton
            app.acabe_ingresar_aristas.Visible='off';
            app.acabe_ingresar_aristas.Enable='off';

            %activa el boton advertencia incidencia
            app.advertencia_incidencia.Visible='on';
            app.MatrizIncidenciaButton.Visible='on';
                
            %activar el mensaje obtener las matrices
            app.ObtenerlasMatricesLabel.Visible='on';
            app.ObtenerlasMatricesLabel.Enable='on';

            %ingresar arista
            app.IngresarAristaButton.Visible='off';
            app.IngresarAristaButton.Enable='off';

            %activa el boton matriz adyacencia
            app.MatrizAdyacenciaButton.Visible='on';

        end

        % Button pushed function: NoButton
        function NoButtonPushed(app, event)
                   
                    app.Detector_paralelas=0;%se recetea el detector de paralelas
                    %desactiva el label que dice " ya existe esa arista"
                    app.ya_existe_la_arista.Visible='off';
                    app.ya_existe_la_arista.Enable='off';
                    
                    %cambia el texto del boton ingresar arista
                    app.IngresarAristaButton.Text='Ingresar Arista';

                    app.NoButton.Enable='off';
                    app.NoButton.Visible='off';

                     %ctiva los spinners
                    app.Vertice1.Enable='on';
                    app.Vertice2.Enable='on';
                    app.Vertice1.Visible='on';
                    app.Vertice2.Visible='on';
                
                    %activa las etiquetas vertice
                      
                     app.verticeLabel_1.Visible='on';
                     app.verticeLabel_2.Visible='on';

                      %activa el boton acabe ingresar aristas
                    app.acabe_ingresar_aristas.Visible='on';
                    app.acabe_ingresar_aristas.Enable='on';


        end

        % Button pushed function: VerMatrizEnPrograma
        function VerMatrizEnProgramaButtonPushed(app, event)
            app.VerMatrizEnPrograma.Visible='off';
            app.VerMatrizEnPrograma.Enable='off';
            app.visualizar_tabla=1;
        end

        % Button pushed function: VergraficaButton
        function VergraficaButtonPushed(app, event)
             app.h=plot(app.G);
             labeledge(app.h,app.ID_aristas,app.ID_aristas);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Vertice1 = uispinner(app.UIFigure);
            app.Vertice1.AllowEmpty = 'on';
            app.Vertice2 = uispinner(app.UIFigure);
            app.Vertice2.AllowEmpty = 'on';
            app.ConLabel = uilabel(app.UIFigure);
            app.ConLabel.Text = 'Con';
            app.IngresarAristaButton = uibutton(app.UIFigure, 'push');
            app.IngresarAristaButton.ButtonPushedFcn = createCallbackFcn(app, @IngresarAristaButtonPushed, true);
            app.IngresarAristaButton.Text = 'Ingresar Arista';
            app.RegresaraMenuPrincipalButton = uibutton(app.UIFigure, 'push');
            app.RegresaraMenuPrincipalButton.ButtonPushedFcn = createCallbackFcn(app, @RegresaraMenuPrincipalButtonPushed, true);
            app.RegresaraMenuPrincipalButton.Text = 'Regresar a Menu Principal';
            app.MatrizAdyacenciaButton = uibutton(app.UIFigure, 'push');
            app.MatrizAdyacenciaButton.ButtonPushedFcn = createCallbackFcn(app, @MatrizAdyacenciaButtonPushed, true);
            app.MatrizAdyacenciaButton.Visible = 'off';
            app.MatrizAdyacenciaButton.Text = 'Matriz Adyacencia';
            app.MatrizIncidenciaButton = uibutton(app.UIFigure, 'push');
            app.MatrizIncidenciaButton.ButtonPushedFcn = createCallbackFcn(app, @MatrizIncidenciaButtonPushed, true);
            app.MatrizIncidenciaButton.Visible = 'off';
            app.MatrizIncidenciaButton.Text = 'Matriz Incidencia';
            app.Ingrese_arista = uilabel(app.UIFigure);
            app.Ingrese_arista.Text = 'Ingrese la Arista: ';
            app.Num_arista = uilabel(app.UIFigure);
            app.Num_arista.Text = '1';
            app.advertencia_incidencia = uilabel(app.UIFigure);
            app.advertencia_incidencia.Visible = 'off';
            app.advertencia_incidencia.Text = 'Debes ingresar al menos una arista para esta opcion';
            app.acabe_ingresar_aristas = uibutton(app.UIFigure, 'push');
            app.acabe_ingresar_aristas.ButtonPushedFcn = createCallbackFcn(app, @acabe_ingresar_aristasPushed, true);
            app.acabe_ingresar_aristas.Text = 'He terminado de Ingresar Aristas, dame las matrices';
            app.ObtenerlasMatricesLabel = uilabel(app.UIFigure);
            app.ObtenerlasMatricesLabel.Enable = 'off';
            app.ObtenerlasMatricesLabel.Visible = 'off';
            app.ObtenerlasMatricesLabel.Text = 'Obtener las Matrices';
            app.verticeLabel_1 = uilabel(app.UIFigure);
            app.verticeLabel_1.Text = 'vertice';
            app.verticeLabel_2 = uilabel(app.UIFigure);
            app.verticeLabel_2.Text = 'vertice';
            app.ya_existe_la_arista = uilabel(app.UIFigure);
            app.ya_existe_la_arista.Enable = 'off';
            app.ya_existe_la_arista.Visible = 'off';
            app.ya_existe_la_arista.Text = 'Ya existe la arista que va del vertice  al vertice 2, deseas ingresarla otra vez como una linea paralela??';
            app.NoButton = uibutton(app.UIFigure, 'push');
            app.NoButton.ButtonPushedFcn = createCallbackFcn(app, @NoButtonPushed, true);
            app.NoButton.Enable = 'off';
            app.NoButton.Visible = 'off';
            app.NoButton.Text = 'No';
            app.VerMatrizEnPrograma = uibutton(app.UIFigure, 'push');
            app.VerMatrizEnPrograma.ButtonPushedFcn = createCallbackFcn(app, @VerMatrizEnProgramaButtonPushed, true);
            app.VerMatrizEnPrograma.Enable = 'off';
            app.VerMatrizEnPrograma.Visible = 'off';
            app.VerMatrizEnPrograma.Text = 'Ver Matrices dentro del programa';
            app.SihacerradolagraficaaccidentalmenteLabel = uilabel(app.UIFigure);
            app.SihacerradolagraficaaccidentalmenteLabel.Text = 'Si ha cerrado la grafica accidentalmente';
            app.DeclickparareactivarlaLabel = uilabel(app.UIFigure);
            app.DeclickparareactivarlaLabel.Text = 'De click para reactivarla';
            app.VergraficaButton = uibutton(app.UIFigure, 'push');
            app.VergraficaButton.ButtonPushedFcn = createCallbackFcn(app, @VergraficaButtonPushed, true);
            app.VergraficaButton.Text = 'Ver grafica';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.NoButton.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GRAFICAS(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end