classdef Ingresar_vertices < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ContinuarButton                 matlab.ui.control.Button
        Advertencia                     matlab.ui.control.Label
        GrafOrDi                        matlab.ui.control.Label
        CantidaddeverticesSpinner       matlab.ui.control.Spinner
        CantidaddeverticesSpinnerLabel  matlab.ui.control.Label
        IngresalacantidaddeverticesquetendratuLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        
        select=-1; % Description
        VERTICES=0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, seleccion)
            if seleccion==1%esta opcion se ocupa para grafica
                app.select=seleccion;
                app.GrafOrDi.Text='Grafica';
            else
                app.select=seleccion;
                app.GrafOrDi.Text='DiGrafica';
            end%end de las condicionales
 
        end

        % Value changed function: CantidaddeverticesSpinner
        function CantidaddeverticesSpinnerValueChanged(app, event)
            value = app.CantidaddeverticesSpinner.Value;
                if value>10
                    app.Advertencia.Text='El numero de vertices es muy grande';
                else
                    app.Advertencia.Text='';
                end
    
               
            app.VERTICES=value;
        end

        % Button pushed function: ContinuarButton
        function ContinuarButtonPushed(app, event)
            if app.select==1
                GRAFICAS(app.CantidaddeverticesSpinner.Value);
                %app.VERTICES
                delete(app);
            else
                DIGRAFICAS(app.CantidaddeverticesSpinner.Value);
                delete(app);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.IngresalacantidaddeverticesquetendratuLabel = uilabel(app.UIFigure);
            app.IngresalacantidaddeverticesquetendratuLabel.Text = 'Ingresa la cantidad de vertices que tendra tu';
            app.CantidaddeverticesSpinnerLabel = uilabel(app.UIFigure);
            app.CantidaddeverticesSpinnerLabel.Text = 'Cantidad de vertices';
            app.CantidaddeverticesSpinner = uispinner(app.UIFigure);
            app.CantidaddeverticesSpinner.Limits = [1 100];
            app.CantidaddeverticesSpinner.ValueChangedFcn = createCallbackFcn(app, @CantidaddeverticesSpinnerValueChanged, true);
            app.CantidaddeverticesSpinner.Value = 1;
            app.GrafOrDi = uilabel(app.UIFigure);
            app.GrafOrDi.Text = '';
            app.Advertencia = uilabel(app.UIFigure);
            app.Advertencia.Text = '';
            app.ContinuarButton = uibutton(app.UIFigure, 'push');
            app.ContinuarButton.ButtonPushedFcn = createCallbackFcn(app, @ContinuarButtonPushed, true);
            app.ContinuarButton.Text = 'Continuar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Ingresar_vertices(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end