classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        seleccion                 matlab.ui.control.Label
        ClickparacontinuarButton  matlab.ui.control.Button
        opciones                  matlab.ui.container.ButtonGroup
        Finalizarprograma         matlab.ui.control.RadioButton
        Digrafica                 matlab.ui.control.RadioButton
        Grafica                   matlab.ui.control.RadioButton
        MenudeopcionesLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        posicion=1; % Description
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
        end

        % Button pushed function: ClickparacontinuarButton
        function ClickparacontinuarButtonPushed(app, event)
            if app.posicion==3
                
                ruta_archivo = 'cerrar_excel.bat';
                comando = ['del ', ruta_archivo];   
                system(comando);
                delete(app);
            else
                Ingresar_vertices(app.posicion);
                delete(app);
            end
        end

        % Selection changed function: opciones
        function opcionesSelectionChanged(app, event)
            if app.Finalizarprograma.Value==1
                app.seleccion.Text='Has seleccionado finalizar Programa';
                app.posicion=3;
            elseif app.Grafica.Value==1
                app.posicion=1;
                app.seleccion.Text='Has seleccionado Grafica';
            elseif app.Digrafica.Value==1 
                app.posicion=2;
                app.seleccion.Text='Has seleccionado Digrafica';
            end%se finaliza la condicional
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.MenudeopcionesLabel = uilabel(app.UIFigure);
            app.MenudeopcionesLabel.Text = 'Menu de opciones';
            app.opciones = uibuttongroup(app.UIFigure);
            app.opciones.SelectionChangedFcn = createCallbackFcn(app, @opcionesSelectionChanged, true);
            app.opciones.Title = 'Selecciona Una opcion';
            app.Grafica = uiradiobutton(app.opciones);
            app.Grafica.Text = 'Graficas';
            app.Grafica.Value = true;
            app.Digrafica = uiradiobutton(app.opciones);
            app.Digrafica.Text = 'Digraficas';
            app.Finalizarprograma = uiradiobutton(app.opciones);
            app.Finalizarprograma.Text = 'Finalizar programa';
            app.ClickparacontinuarButton = uibutton(app.UIFigure, 'push');
            app.ClickparacontinuarButton.ButtonPushedFcn = createCallbackFcn(app, @ClickparacontinuarButtonPushed, true);
            app.ClickparacontinuarButton.Text = 'Click para continuar';
            app.seleccion = uilabel(app.UIFigure);
            app.seleccion.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end